classdef ECE303 < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        ECEModule6Label          matlab.ui.control.Label
        DUTYCYCLEEditField       matlab.ui.control.NumericEditField
        DUTYCYCLEEditFieldLabel  matlab.ui.control.Label
        STARTButton              matlab.ui.control.Button
        UIAxes_5                 matlab.ui.control.UIAxes
        UIAxes_4                 matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(comp, event)
% Check if the START button is pressed
    if app.STARTButton.Value
        % Open the serial port (replace 'COMx' with your actual port)
        s = serialport('/dev/cu.usbmodem101', 9600);

        % Initialize empty arrays to store data
        dutyCycle = [];
        current = [];
        voltage = [];
        resistance = [];

        % Start an infinite loop to read and update data
        while true
            % Read a line of data from the serial port
            data = readline(s);

            % Split the data into components
            components = str2double(strsplit(data, ','));

            % Check if the correct number of components is received
            if numel(components) == 4
                % Update the data arrays
                dutyCycle = [dutyCycle, components(1)];
                current = [current, components(2)];
                voltage = [voltage, components(3)];
                resistance = [resistance, components(4)];

                % Update the plots
                plot(app.UIAxes_3, dutyCycle, current, '-o');
                title(app.UIAxes_3, 'Photocell Current vs Duty Cycle');
                xlabel(app.UIAxes_3, 'Duty Cycle');
                ylabel(app.UIAxes_3, 'Current');

                plot(app.UIAxes_4, dutyCycle, voltage, '-o');
                title(app.UIAxes_4, 'Photocell Voltage vs Duty Cycle');
                xlabel(app.UIAxes_4, 'Duty Cycle');
                ylabel(app.UIAxes_4, 'Voltage');

                plot(app.UIAxes_5, dutyCycle, resistance, '-o');
                title(app.UIAxes_5, 'Photocell Resistance vs Duty Cycle');
                xlabel(app.UIAxes_5, 'Duty Cycle');
                ylabel(app.UIAxes_5, 'Resistance');

                % Update the DUTYCYCLEEditField with the latest duty cycle value
                app.DUTYCYCLEEditField.Value = components(1);

                drawnow; % Refresh the plots

                % Check if the STOP button is pressed
                if ~app.STARTButton.Value
                    break; % Exit the loop if the STOP button is pressed
                end
            end
        end

        % Close the serial port
        clear s;

        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
   
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 528 498];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.UIAxes_3 = uiaxes(comp);
            title(comp.UIAxes_3, 'Title')
            xlabel(comp.UIAxes_3, 'X')
            ylabel(comp.UIAxes_3, 'Y')
            zlabel(comp.UIAxes_3, 'Z')
            comp.UIAxes_4 = uiaxes(comp);
            title(comp.UIAxes_4, 'Title')
            xlabel(comp.UIAxes_4, 'X')
            ylabel(comp.UIAxes_4, 'Y')
            zlabel(comp.UIAxes_4, 'Z')
            comp.UIAxes_5 = uiaxes(comp);
            title(comp.UIAxes_5, 'Title')
            xlabel(comp.UIAxes_5, 'X')
            ylabel(comp.UIAxes_5, 'Y')
            zlabel(comp.UIAxes_5, 'Z')
            comp.STARTButton = uibutton(comp, 'push');
            comp.STARTButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @STARTButtonPushed, true);
            comp.STARTButton.Text = 'START';
            comp.DUTYCYCLEEditFieldLabel = uilabel(comp);
            comp.DUTYCYCLEEditFieldLabel.Text = 'DUTY CYCLE';
            comp.DUTYCYCLEEditField = uieditfield(comp, 'numeric');
            comp.ECEModule6Label = uilabel(comp);
            comp.ECEModule6Label.Text = 'ECE Module 6';
        end
    end
end